classdef SpectralTrainFig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        uipanel3                       matlab.ui.container.Panel
        pb_fig_band                    matlab.ui.control.Button
        pb_fig_all_lists               matlab.ui.control.Button
        pb_close_all                   matlab.ui.control.Button
        pb_fig_go_min                  matlab.ui.control.Button
        pb_fig_about                   matlab.ui.control.Button
        pb_fig_go                      matlab.ui.control.Button
        text14                         matlab.ui.control.Label
        pm_analysis_start              matlab.ui.control.DropDown
        pm_analysis_parameters_monitor_id  matlab.ui.control.DropDown
        text10                         matlab.ui.control.Label
        cb_fig_compute_coherence       matlab.ui.control.CheckBox
        checkbox2                      matlab.ui.control.CheckBox
        checkbox3                      matlab.ui.control.CheckBox
        text23                         matlab.ui.control.Label
        uipanel1                       matlab.ui.container.Panel
        pb_fig_set_bands               matlab.ui.control.Button
        text22                         matlab.ui.control.Label
        e_description_xml_suffix       matlab.ui.control.EditField
        text20                         matlab.ui.control.Label
        e_description_result_folder    matlab.ui.control.EditField
        e_description_data_folder      matlab.ui.control.EditField
        e_description_output_file_prefix  matlab.ui.control.EditField
        e_description_analysis_description  matlab.ui.control.EditField
        text4                          matlab.ui.control.Label
        text3                          matlab.ui.control.Label
        pb_description_result_folder   matlab.ui.control.Button
        pb_description_data_folder     matlab.ui.control.Button
        text2                          matlab.ui.control.Label
        text1                          matlab.ui.control.Label
        uipanel2                       matlab.ui.container.Panel
        edit12                         matlab.ui.control.EditField
        text21                         matlab.ui.control.Label
        e_analysis_sr_paritioning_varaible  matlab.ui.control.EditField
        pm_analysis_spectral_settings  matlab.ui.control.DropDown
        text13                         matlab.ui.control.Label
        pm_reference_method            matlab.ui.control.DropDown
        text12                         matlab.ui.control.Label
        pm_analysis_parameters_beta    matlab.ui.control.DropDown
        pm_analysis_parameters_delta   matlab.ui.control.DropDown
        e_analysis_parameters_reference_signals  matlab.ui.control.EditField
        text9                          matlab.ui.control.Label
        text8                          matlab.ui.control.Label
        text7                          matlab.ui.control.Label
        text6                          matlab.ui.control.Label
        text5                          matlab.ui.control.Label
        e_analysis_parameters_analysis_signals  matlab.ui.control.EditField
        text19                         matlab.ui.control.Label
    end

    
    methods (Access = private)
        function pm_partitioned_file_list_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to pm_partitioned_file_list (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SpectralTrainFig_OpeningFcn(app, varargin)
            % --- Executes just before SpectralTrainFig is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to SpectralTrainFig (see VARARGIN)
            
            % Choose default command line output for SpectralTrainFig
            handles.output = hObject;
            
            % Clear edit text boxes
            set(handles.e_description_analysis_description, 'String',' ');
            set(handles.e_description_output_file_prefix, 'String',' ');
            set(handles.e_description_data_folder, 'String',' ');
            set(handles.e_description_result_folder, 'String',' ');
            set(handles.e_analysis_sr_paritioning_varaible, 'String','{ }');
            set(handles.e_analysis_parameters_analysis_signals, 'String',' ');
            set(handles.e_analysis_parameters_reference_signals, 'String',' ');
            set(handles.edit12, 'String',' ');
            set(handles.e_description_xml_suffix, 'String','-edf.xml');
            
            % Inactivate buttons until analysis folders are set
            set(handles.pb_fig_band, 'enable','off');
            set(handles.pb_fig_go_min, 'enable','off');
            set(handles.pb_fig_go, 'enable','off');
            set(handles.pb_fig_all_lists, 'enable','off');
            set(handles.edit12, 'enable', 'on');
            
            
            % Set Defaults to SOF
            set(handles.e_description_analysis_description, 'String','Spectral Analysis');
            set(handles.e_description_output_file_prefix, 'String','study__');
            set(handles.e_analysis_parameters_analysis_signals, 'String','{''C3'',''C4''}');
            set(handles.e_analysis_parameters_reference_signals, 'String','{''A2'',''A1''}');
            set(handles.edit12, 'String','{''ECG''}');
            
            % Set Default to not perform ECG artifact decontamination
            set(handles.checkbox2, 'Value',0)
            
            % Set Default to not perform cycle analysis
            set(handles.checkbox3, 'Value',0)
            
            % Get Monitor Positions and set to first monitor
            monitorPositionsStrCell = ConvertMonitorPosToFigPos;
            set(handles.pm_analysis_parameters_monitor_id, ...
                'String', monitorPositionsStrCell);
            
            % Define GUI variables
            handles.folderSeperator = '\';
            handles.data_folder_path = cd;
            handles.data_folder_path_is_selected = 0;
            handles.result_folder_path = cd;
            handles.result_folder_path_is_selected = 0;
            handles.edfFileListName = '';
            handles.splitFileListCellwLabels = {};
            
            % Define Band Summary files
            handles.band_setting_fn = '';
            handles.band_setting_pn = strcat(cd, handles.folderSeperator);
            handles.band_setting_fn_selected = 0;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pb_close_all
        function pb_close_all_Callback(app, event)
            % --- Executes on button press in pb_close_all.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_close_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            SpectralTrainClass.CloseChildrenFigures;
        end

        % Button pushed function: pb_description_data_folder
        function pb_description_data_folder_Callback(app, event)
            % --- Executes on button press in pb_description_data_folder.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_description_data_folder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Get current data folder path
            data_folder_path = handles.data_folder_path;
            
            % Prompt user for folder
            dialogTitle = 'Select Data Source Folder';
            [folder_path folder_is_selected ] = ...
                                  pb_select_data_folder(data_folder_path, dialogTitle);
            
            % Check if a folder was selected
            if folder_is_selected == 1
                % write file name to dialog box
                set(handles.e_description_data_folder, 'String', folder_path);
                guidata(hObject, handles);
            
                % Save file information to globals
                handles.data_folder_path = folder_path;
                handles.data_folder_path_is_selected = folder_is_selected;
            
                % Adjust buttons if necssary
                if and(logical(handles.data_folder_path_is_selected), ...
                       logical(handles.result_folder_path_is_selected))
                    set(handles.pb_fig_band, 'enable','on');
                    set(handles.pb_fig_go_min, 'enable','on');
                    set(handles.pb_fig_go, 'enable','on');
                    set(handles.pb_fig_all_lists, 'enable','on');
                end
            
                % Save new data
                guidata(hObject, handles);

                % Bring Focus to app windows
                figure(app.figure1);
            end
        end

        % Button pushed function: pb_description_result_folder
        function pb_description_result_folder_Callback(app, event)
            % --- Executes on button press in pb_description_result_folder.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_description_result_folder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Get current data folder path
            result_folder_path = handles.result_folder_path;
            
            % Prompt user for folder
            dialogTitle = 'Select Result Folder';
            [folder_path folder_is_selected ] = ...
                                  pb_select_data_folder(result_folder_path, dialogTitle);
            
            % Check if a folder was selected
            if folder_is_selected == 1
                % write file name to dialog box
                set(handles.e_description_result_folder, 'String', folder_path);
                guidata(hObject, handles);
            
                % Save file information to globals
                handles.result_folder_path = folder_path;
                handles.result_folder_path_is_selected = folder_is_selected;
            
                % Adjust buttons if necssary
                if and(logical(handles.data_folder_path_is_selected), ...
                       logical(handles.result_folder_path_is_selected))
                    set(handles.pb_fig_band, 'enable','on');
                    set(handles.pb_fig_go_min, 'enable','on');
                    set(handles.pb_fig_go, 'enable','on');
                    set(handles.pb_fig_all_lists, 'enable','on');
                end
            
                % Save new data
                guidata(hObject, handles);

                % Bring Focus to app windows
                figure(app.figure1);
            end
        end

        % Button pushed function: pb_fig_about
        function pb_fig_about_Callback(app, event)
            % --- Executes on button press in pb_fig_about.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_fig_about (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            SpectralTrainFigAbout
        end

        % Button pushed function: pb_fig_all_lists
        function pb_fig_all_lists_Callback(app, event)
            % --- Executes on button press in pb_fig_all_lists.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_fig_all_lists (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %------------------------------------------------------------ Get File List
            
            % Get Path/File Information
            summaryFilePath = handles.result_folder_path;
            handles.edfFileListName = get(handles.e_description_output_file_prefix, 'String');
            edfFileListName = strcat(summaryFilePath, '\',handles.edfFileListName,'_Edf_File_List.xls');
            edf_pn = handles.data_folder_path;
            %edf_pn = edf_pn(1:end-1);
            edf_FolderName = handles.data_folder_path;
            
            % Echo Status to console
            fprintf('\nStarting search for EDF files in folder: %s\n\n', ...
                edf_FolderName);
            
            % Generate Matched files
            GetMatchedSleepEdfXmlFiles(edf_pn, edfFileListName);
            splitFileListCellwLabels = ...
                    GetMatchedSleepEdfXmlFiles(edf_pn, edfFileListName);
            
            % Save file list
            handles.splitFileListCellwLabels = splitFileListCellwLabels;
            
            % ----------------------------------------- Check sampling rate information
            
            % Get Path/File Information
            summaryFilePath = handles.result_folder_path;
            handles.edfFileListName = get(handles.e_description_output_file_prefix, 'String');
            edfFileListName = strcat(summaryFilePath, handles.edfFileListName,'_Edf_File_List.xls');
            edfFileListNamePrefix = strcat(summaryFilePath, '\', handles.edfFileListName,'_Edf_File_List');
            
            % Generate EDF File list
            summaryFilePath = strcat(handles.result_folder_path, '\');
            handles.edfFileListName = get(handles.e_description_output_file_prefix, 'String');
            edfFileListName = strcat(summaryFilePath, handles.edfFileListName,'_Edf_File_List.xls');
            
            % Get Path/File Information
            summaryFilePath = handles.result_folder_path;
            summaryFileName = get(handles.e_description_output_file_prefix, 'String');
            summaryFileName = strcat(summaryFilePath, '\', summaryFileName,'_FilePartitionSummary.xls');
            
            % XLS File name
            xlsFileList = edfFileListName;
            xlsFileSummaryOut = summaryFileName;
            
            % Get Signal Summary
            filePartitioningLabel = eval(get(handles.e_analysis_sr_paritioning_varaible, 'String'));
            
            % Check if partioining is necessary
            if ~isempty(filePartitioningLabel)
            
                % Update User
                fprintf('\nSegmenting file list\n');
            
                % Create class
                besObj = BlockEdfSummarizeClass(xlsFileList, xlsFileSummaryOut);
                besObj.filePartitioningLabel = filePartitioningLabel;
                besObj.splitFileListCellwLabels = handles.splitFileListCellwLabels;
                besObj.edfFileListNamePrefix = edfFileListNamePrefix;
                besObj = besObj.segmentFileList;
            
                % Get sampling information
                uniqueSamplingRates = besObj.uniqueSamplingRates;
                fileNameCell = besObj.fileNameCell;
                fileListCell = besObj.fileListCell;
            
                % Update User
                for f = 1:length(uniqueSamplingRates)
                    fprintf('Segmented files written to (%.0f):\t%s\n', ...
                        uniqueSamplingRates(f), fileNameCell{f});
                end
            
                %--------------------------------------------------------- Processing Steps
                for f = 1:length(uniqueSamplingRates)
                    % Echo status to console
                    fprintf('\n\nProcessing %.0f of %.0f file (Sampling Rate = %.0f)\n%s\n', ...
                        f, length(uniqueSamplingRates), uniqueSamplingRates(f), fileNameCell{f});
            
            
                    % Set Partitioned file varaibles
                    matchedCell = fileListCell{f};
                    checkFile = fileNameCell{f};
            
                    % Get handle information
                    band_setting_fn = handles.band_setting_fn;
                    band_setting_pn = handles.band_setting_pn;
                    band_setting_fn_selected = handles.band_setting_fn_selected;
            
                    % Get Analysis Information from interface
                    analysisDescription = ...
                        get(handles.e_description_analysis_description, 'String');
                    outputFilePrefix = ...
                        get(handles.e_description_output_file_prefix, 'String');
                    dataFolder =  strcat(...
                        get(handles.e_description_data_folder, 'String'),...
                        handles.folderSeperator);
                    resultFolder =  strcat(...
                        get(handles.e_description_result_folder, 'String'), ...
                        handles.folderSeperator);
                    analysisSignals =  eval(...
                        get(handles.e_analysis_parameters_analysis_signals, 'String'));
                    referenceSignals =  eval(......
                        get(handles.e_analysis_parameters_reference_signals, 'String'));
                    referenceMethod = get(handles.pm_reference_method, 'Value');
                    deltaThStr = ...
                        get(handles.pm_analysis_parameters_delta, 'String');
                    deltaTh = str2num(deltaThStr{...
                        get(handles.pm_analysis_parameters_delta, 'Value')});
                    betaThStr =  ...
                        get(handles.pm_analysis_parameters_beta, 'String');
                    betaTh = str2num(betaThStr{...
                        get(handles.pm_analysis_parameters_beta, 'Value')});
                    monitorIDStr =  ...
                        get(handles.pm_analysis_parameters_monitor_id, 'String');
                    monitorID = eval(monitorIDStr{...
                        get(handles.pm_analysis_parameters_monitor_id, 'Value')});
                    cb_fig_compute_coherence =  ...
                        get(handles.cb_fig_compute_coherence, 'Value');
                    pm_analysis_spectral_settings =  ...
                        get(handles.pm_analysis_spectral_settings, 'Value');
                    analysisStartStr =  ...
                        get(handles.pm_analysis_start, 'String');
                    startFile = str2num(analysisStartStr{...
                        get(handles.pm_analysis_start, 'Value')});
                    denoise_ecg =  ...
                get(handles.checkbox2, 'Value');
            if denoise_ecg
                set(handles.edit12,'enable','on')
            end
            cycles_analysis=...
                get(handles.checkbox3, 'Value');
            
                    % Create spectral analysis structure
                    stcStruct.analysisDescription = analysisDescription;
                    stcStruct.StudyEdfFileListResultsFn = strcat(outputFilePrefix, ...
                        '_FileList.xlsx');
                    stcStruct.StudyEdfDir = dataFolder;
                    stcStruct.StudyEdfResultDir = resultFolder;
                    stcStruct.xlsFileContentCheckSummaryOut =  strcat(outputFilePrefix, ...
                        '_FileLisWithCheck.xlsx');
                    stcStruct.xlsFileContentCheckSummaryOut =  strcat(outputFilePrefix, ...
                        '_', num2str(uniqueSamplingRates(f)),'_FileLisWithCheck.xlsx');
                    stcStruct.analysisSignals = analysisSignals;
                    stcStruct.referenceSignals = referenceSignals;
                    stcStruct.requiredSignals = [analysisSignals referenceSignals];
                    stcStruct.StudySpectrumSummary = strcat( ...
                        outputFilePrefix, 'SpectralSummary.xlsx');
                    stcStruct.StudySpectrumSummary = strcat( ...
                        outputFilePrefix, '_', num2str(uniqueSamplingRates(f)), ...
                        '_SpectralSummary.xlsx');
                    stcStruct.StudyBandSummary = strcat( ...
                        outputFilePrefix, '_BandSummary.xlsx');
                    stcStruct.StudyBandSummary = strcat( ...
                        outputFilePrefix, '_', num2str(uniqueSamplingRates(f)), ...
                        '_BandSummary.xlsx');
                    stcStruct.checkFile = strcat(stcStruct.StudyEdfResultDir, ...
                            stcStruct.StudyEdfFileListResultsFn);
                    stcStruct.checkFile = checkFile;
            
                    % Create class object
                    stcObj = SpectralTrainClass(stcStruct);
            
                    % Define options for minimum reccomendedoutput
                    stcObj.referenceMethodIndex = referenceMethod;
                    stcObj.SUMMARIZE_BANDS = 1;
                    stcObj.EXPORT_BAND_SUMMARY = 1;
                    stcObj.PLOT_CALIBRATION_TEST = 0;
                    stcObj.PLOT_HYPNOGRAM = 1;
                    stcObj.PLOT_ARTIFACT_SUMMARY = 1;
                    stcObj.PLOT_SPECTRAL_SUMMARY = 1;
                    stcObj.PLOT_NREM_REM_SPECTRUM = 1;
                    stcObj.OUTPUT_AVERAGE_SPECTROGRAMS = 1;
                    stcObj.PLOT_BAND_ACTIVITY = 1;
                    stcObj.artifactTH = [deltaTh betaTh];
                    stcObj.figPos = monitorID;
                    stcObj.GENERATE_FILE_LIST = 0;
                    stcObj.CREATE_POWER_POINT_SUMMARY = 1;
                    stcObj.EXPORT_SPECTRAL_DETAILS = 1;
                    stcObj.COMPUTE_TOTAL_POWER = 1;
                    stcObj.EXPORT_TOTAL_POWER = 1;
            
                    % Set file identification parameters
                    xmlSuffix = get(handles.e_description_xml_suffix, 'String');
                    stcObj.xmlSuffix = xmlSuffix;
            
                    % Spectral parameters
                    if (pm_analysis_spectral_settings == 2)
                        % Switch to SHHS settings
                        stcObj.noverlap = 6;
                        stcObj.spectralBinWidth = 5;
                        stcObj.windowFunctionIndex = 3; % Hanning
                        stcObj.AVERAGE_ADJACENT_BANDS = 0;
                    end
            
                    % Band settings
                    if band_setting_fn_selected == 1
                        % Load band structure
                        bandFn = strcat(band_setting_pn, band_setting_fn);
                        bandStruct = stcObj.LoadBandSettings(bandFn);
            
                        % Set band variables
                        stcObj.bandsOfInterest = bandStruct.bandsOfInterest;
                        stcObj.bandsOfInterestLabels = bandStruct.bandsOfInterestLabels;
                        stcObj.bandsOfInterestLatex = bandStruct.bandsOfInterestLatex;
                        stcObj.bandColors = bandStruct.bandColors;
                    end
            
                    % Compute Coherence
                    stcObj.COHERENCE_COMPUTE_COHERENCE = cb_fig_compute_coherence;
            
                    % Set start iterion
                    stcObj.startFile = startFile;
            
                    % Execute analysis
                    stcObj = stcObj.computeSpectralTrain(matchedCell);
                end
            else
                % Process using the single file version
                pb_fig_go_Callback(hObject, eventdata, handles)
            end
        end

        % Button pushed function: pb_fig_band
        function pb_fig_band_Callback(app, event)
            % --- Executes on button press in pb_fig_band.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_fig_band (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Get handle information
            band_setting_fn = handles.band_setting_fn;
            band_setting_pn = handles.band_setting_pn;
            band_setting_fn_selected = handles.band_setting_fn_selected;
            
            % Get Analysis Information from interface
            analysisDescription = ...
                get(handles.e_description_analysis_description, 'String');
            outputFilePrefix = ...
                get(handles.e_description_output_file_prefix, 'String');
            dataFolder =  strcat(...
                get(handles.e_description_data_folder, 'String'),...
                handles.folderSeperator);
            resultFolder =  strcat(...
                get(handles.e_description_result_folder, 'String'), ...
                handles.folderSeperator);
            analysisSignals =  eval(...
                get(handles.e_analysis_parameters_analysis_signals, 'String'));
            referenceSignals =  eval(......
                get(handles.e_analysis_parameters_reference_signals, 'String'));
            referenceMethod = get(handles.pm_reference_method, 'Value');
            deltaThStr = ...
                get(handles.pm_analysis_parameters_delta, 'String');
            deltaTh = str2num(deltaThStr{...
                get(handles.pm_analysis_parameters_delta, 'Value')});
            betaThStr =  ...
                get(handles.pm_analysis_parameters_beta, 'String');
            betaTh = str2num(betaThStr{...
                get(handles.pm_analysis_parameters_beta, 'Value')});
            monitorIDStr =  ...
                get(handles.pm_analysis_parameters_monitor_id, 'String');
            monitorID = eval(monitorIDStr{...
                get(handles.pm_analysis_parameters_monitor_id, 'Value')});
            cb_fig_compute_coherence =  ...
                get(handles.cb_fig_compute_coherence, 'Value');
            pm_analysis_spectral_settings =  ...
                get(handles.pm_analysis_spectral_settings, 'Value');
            analysisStartStr =  ...
                get(handles.pm_analysis_start, 'String');
            startFile = str2num(analysisStartStr{...
                get(handles.pm_analysis_start, 'Value')});
            denoise_ecg =  ...
                get(handles.checkbox2, 'Value');
            if denoise_ecg
                set(handles.edit12,'enable','on')
            end
            cycles_analysis=...
                get(handles.checkbox3, 'Value');
            
            % Create spectral analysis structure
            stcStruct.analysisDescription = analysisDescription;
            stcStruct.StudyEdfFileListResultsFn = strcat(outputFilePrefix, ...
                '_FileList.xlsx');
            stcStruct.StudyEdfDir = dataFolder;
            stcStruct.StudyEdfResultDir = resultFolder;
            stcStruct.xlsFileContentCheckSummaryOut =  strcat(outputFilePrefix, ...
                '_FileLisWithCheck.xlsx');
            stcStruct.analysisSignals = analysisSignals;
            stcStruct.referenceSignals = referenceSignals;
            stcStruct.requiredSignals = [analysisSignals referenceSignals];
            stcStruct.StudySpectrumSummary = strcat( ...
                outputFilePrefix, 'SpectralSummary.xlsx');
            stcStruct.StudyBandSummary = strcat( ...
                outputFilePrefix, 'BandSummary.xlsx');
            stcStruct.checkFile = strcat(stcStruct.StudyEdfResultDir, ...
                    stcStruct.StudyEdfFileListResultsFn);
            
            % Create class object
            stcObj = SpectralTrainClass(stcStruct);
            
            % Define options for minimum reccomendedoutput
            stcObj.referenceMethodIndex = referenceMethod;
            stcObj.SUMMARIZE_BANDS = 1;
            stcObj.EXPORT_BAND_SUMMARY = 1;
            stcObj.PLOT_CALIBRATION_TEST = 0;
            stcObj.PLOT_HYPNOGRAM = 0;
            stcObj.PLOT_ARTIFACT_SUMMARY = 0;
            stcObj.PLOT_SPECTRAL_SUMMARY = 0;
            stcObj.PLOT_NREM_REM_SPECTRUM = 1;
            stcObj.OUTPUT_AVERAGE_SPECTROGRAMS = 1;
            stcObj.PLOT_BAND_ACTIVITY = 0;
            stcObj.artifactTH = [deltaTh betaTh];
            stcObj.figPos = monitorID;
            stcObj.GENERATE_FILE_LIST = 1;
            stcObj.CREATE_POWER_POINT_SUMMARY = 0;
            stcObj.EXPORT_INDIVIDUAL_BAND_SUMMARY = 0;
            
            stcObj.EXPORT_SPECTRAL_DETAILS = 0;
            stcObj.COMPUTE_TOTAL_POWER = 0;
            stcObj.EXPORT_TOTAL_POWER = 0;
            
            % Spectral parameters
            if (pm_analysis_spectral_settings == 2)
                % Switch to SHHS settings
                stcObj.noverlap = 6;
                stcObj.spectralBinWidth = 5;
                stcObj.windowFunctionIndex = 3; % Hanning
                stcObj.AVERAGE_ADJACENT_BANDS = 0;
            end
            
            % Band settings
            if band_setting_fn_selected == 1
                % Load band structure
                bandFn = strcat(band_setting_pn, band_setting_fn);
                bandStruct = stcObj.LoadBandSettings(bandFn);
            
                % Set band variables
                stcObj.bandsOfInterest = bandStruct.bandsOfInterest;
                stcObj.bandsOfInterestLabels = bandStruct.bandsOfInterestLabels;
                stcObj.bandsOfInterestLatex = bandStruct.bandsOfInterestLatex;
                stcObj.bandColors = bandStruct.bandColors;
            end
            
            % Set start iterion
            stcObj.startFile = startFile;
            
            % Coherence parameters
            stcObj.COHERENCE_COMPUTE_COHERENCE = cb_fig_compute_coherence;
            
            % Execute analysis
            stcObj = stcObj.computeSpectralTrain;
        end

        % Button pushed function: pb_fig_go
        function pb_fig_go_Callback(app, event)
            % --- Executes on button press in pb_fig_go.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_fig_go (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Get handle information
            band_setting_fn = handles.band_setting_fn;
            band_setting_pn = handles.band_setting_pn;
            band_setting_fn_selected = handles.band_setting_fn_selected;
            
            % Get Analysis Information from interface
            analysisDescription = ...
                get(handles.e_description_analysis_description, 'String');
            outputFilePrefix = ...
                get(handles.e_description_output_file_prefix, 'String');
            dataFolder =  strcat(...
                get(handles.e_description_data_folder, 'String'),...
                handles.folderSeperator);
            resultFolder =  strcat(...
                get(handles.e_description_result_folder, 'String'), ...
                handles.folderSeperator);
            analysisSignals =  eval(...
                get(handles.e_analysis_parameters_analysis_signals, 'String'));
            referenceSignals =  eval(......
                get(handles.e_analysis_parameters_reference_signals, 'String'));
            referenceMethod = get(handles.pm_reference_method, 'Value');
            deltaThStr = ...
                get(handles.pm_analysis_parameters_delta, 'String');
            deltaTh = str2num(deltaThStr{...
                get(handles.pm_analysis_parameters_delta, 'Value')});
            betaThStr =  ...
                get(handles.pm_analysis_parameters_beta, 'String');
            betaTh = str2num(betaThStr{...
                get(handles.pm_analysis_parameters_beta, 'Value')});
            monitorIDStr =  ...
                get(handles.pm_analysis_parameters_monitor_id, 'String');
            monitorID = eval(monitorIDStr{...
                get(handles.pm_analysis_parameters_monitor_id, 'Value')});
            cb_fig_compute_coherence =  ...
                get(handles.cb_fig_compute_coherence, 'Value');
            pm_analysis_spectral_settings =  ...
                get(handles.pm_analysis_spectral_settings, 'Value');
            analysisStartStr =  ...
                get(handles.pm_analysis_start, 'String');
            startFile = str2num(analysisStartStr{...
                get(handles.pm_analysis_start, 'Value')});
            denoise_ecg =  ...
                get(handles.checkbox2, 'Value');
            ecg_name=eval(...
                get(handles.edit12, 'String'));
            cycles_analysis=...
                get(handles.checkbox3, 'Value');
            
            % Create spectral analysis structure
            stcStruct.analysisDescription = analysisDescription;
            stcStruct.StudyEdfFileListResultsFn = strcat(outputFilePrefix, ...
                '_FileList.xlsx');
            stcStruct.StudyEdfDir = dataFolder;
            stcStruct.StudyEdfResultDir = resultFolder;
            stcStruct.xlsFileContentCheckSummaryOut =  strcat(outputFilePrefix, ...
                '_FileLisWithCheck.xlsx');
            stcStruct.analysisSignals = analysisSignals;
            stcStruct.referenceSignals = referenceSignals;
            stcStruct.denoiseEcg=denoise_ecg;
            stcStruct.ecgName=ecg_name;
            stcStruct.requiredSignals = [analysisSignals referenceSignals];
            stcStruct.StudySpectrumSummary = strcat( ...
                outputFilePrefix, 'SpectralSummary.xlsx');
            stcStruct.StudyBandSummary = strcat( ...
                outputFilePrefix, 'BandSummary.xlsx');
            stcStruct.checkFile = strcat(stcStruct.StudyEdfResultDir, ...
                    stcStruct.StudyEdfFileListResultsFn);
            stcStruct.cycles_analysis=cycles_analysis;
            
            % Create class object
            stcObj = SpectralTrainClass(stcStruct);
            
            % Define options for minimum reccomendedoutput
            stcObj.referenceMethodIndex = referenceMethod;
            stcObj.SUMMARIZE_BANDS = 1;
            stcObj.EXPORT_BAND_SUMMARY = 1;
            stcObj.PLOT_CALIBRATION_TEST = 0;
            stcObj.PLOT_HYPNOGRAM = 1;
            stcObj.PLOT_ARTIFACT_SUMMARY = 1;
            stcObj.PLOT_SPECTRAL_SUMMARY = 1;
            stcObj.PLOT_NREM_REM_SPECTRUM = 1;
            stcObj.OUTPUT_AVERAGE_SPECTROGRAMS = 1;
            stcObj.PLOT_BAND_ACTIVITY = 1;
            stcObj.artifactTH = [deltaTh betaTh];
            stcObj.figPos = monitorID;
            stcObj.GENERATE_FILE_LIST = 1;
            stcObj.CREATE_POWER_POINT_SUMMARY = 1;
            stcObj.EXPORT_SPECTRAL_DETAILS = 1;
            stcObj.COMPUTE_TOTAL_POWER = 1;
            stcObj.EXPORT_TOTAL_POWER = 1;
            
            % Set file identification parameters
            xmlSuffix = get(handles.e_description_xml_suffix, 'String');
            stcObj.xmlSuffix = xmlSuffix;
            
            % Spectral parameters
            if (pm_analysis_spectral_settings == 2)
                % Switch to SHHS settings
                stcObj.noverlap = 6;
                stcObj.spectralBinWidth = 5;
                stcObj.windowFunctionIndex = 3; % Hanning
                stcObj.AVERAGE_ADJACENT_BANDS = 0;
            end
            
            % Band settings
            if band_setting_fn_selected == 1
                % Load band structure
                bandFn = strcat(band_setting_pn, band_setting_fn);
                bandStruct = stcObj.LoadBandSettings(bandFn);
            
                % Set band variables
                stcObj.bandsOfInterest = bandStruct.bandsOfInterest;
                stcObj.bandsOfInterestLabels = bandStruct.bandsOfInterestLabels;
                stcObj.bandsOfInterestLatex = bandStruct.bandsOfInterestLatex;
                stcObj.bandColors = bandStruct.bandColors;
            end
            
            % Compute Coherence
            stcObj.COHERENCE_COMPUTE_COHERENCE = cb_fig_compute_coherence;
            
            % Set start iterion
            stcObj.startFile = startFile;
            
            % Execute analysis
            stcObj = stcObj.computeSpectralTrain;
        end

        % Button pushed function: pb_fig_go_min
        function pb_fig_go_min_Callback(app, event)
            % --- Executes on button press in pb_fig_go_min.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_fig_go_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Get handle information
            band_setting_fn = handles.band_setting_fn;
            band_setting_pn = handles.band_setting_pn;
            band_setting_fn_selected = handles.band_setting_fn_selected;
            
            % Get Analysis Information from interface
            analysisDescription = ...
                get(handles.e_description_analysis_description, 'String');
            outputFilePrefix = ...
                get(handles.e_description_output_file_prefix, 'String');
            dataFolder =  strcat(...
                get(handles.e_description_data_folder, 'String'),...
                handles.folderSeperator);
            resultFolder =  strcat(...
                get(handles.e_description_result_folder, 'String'), ...
                handles.folderSeperator);
            analysisSignals =  eval(...
                get(handles.e_analysis_parameters_analysis_signals, 'String'));
            referenceSignals =  eval(......
                get(handles.e_analysis_parameters_reference_signals, 'String'));
            referenceMethod = get(handles.pm_reference_method, 'Value');
            deltaThStr = ...
                get(handles.pm_analysis_parameters_delta, 'String');
            deltaTh = str2num(deltaThStr{...
                get(handles.pm_analysis_parameters_delta, 'Value')});
            betaThStr =  ...
                get(handles.pm_analysis_parameters_beta, 'String');
            betaTh = str2num(betaThStr{...
                get(handles.pm_analysis_parameters_beta, 'Value')});
            monitorIDStr =  ...
                get(handles.pm_analysis_parameters_monitor_id, 'String');
            monitorID = eval(monitorIDStr{...
                get(handles.pm_analysis_parameters_monitor_id, 'Value')});
            cb_fig_compute_coherence =  ...
                get(handles.cb_fig_compute_coherence, 'Value');
            pm_analysis_spectral_settings =  ...
                get(handles.pm_analysis_spectral_settings, 'Value');
            analysisStartStr =  ...
                get(handles.pm_analysis_start, 'String');
            startFile = str2num(analysisStartStr{...
                get(handles.pm_analysis_start, 'Value')});
            denoise_ecg =  ...
                get(handles.checkbox2, 'Value');
            if denoise_ecg
                set(handles.edit12,'enable','on')
            end
            cycles_analysis=...
                get(handles.checkbox3, 'Value');
            % Create spectral analysis structure
            stcStruct.analysisDescription = analysisDescription;
            stcStruct.StudyEdfFileListResultsFn = strcat(outputFilePrefix, ...
                '_FileList.xlsx');
            stcStruct.StudyEdfDir = dataFolder;
            stcStruct.StudyEdfResultDir = resultFolder;
            stcStruct.xlsFileContentCheckSummaryOut =  strcat(outputFilePrefix, ...
                '_FileLisWithCheck.xlsx');
            stcStruct.analysisSignals = analysisSignals;
            stcStruct.referenceSignals = referenceSignals;
            stcStruct.requiredSignals = [analysisSignals referenceSignals];
            stcStruct.StudySpectrumSummary = strcat( ...
                outputFilePrefix, 'SpectralSummary.xlsx');
            stcStruct.StudyBandSummary = strcat( ...
                outputFilePrefix, 'BandSummary.xlsx');
            stcStruct.checkFile = strcat(stcStruct.StudyEdfResultDir, ...
                    stcStruct.StudyEdfFileListResultsFn);
            
            % Create class object
            stcObj = SpectralTrainClass(stcStruct);
            
            % Set file identification parameters
            xmlSuffix = get(handles.e_description_xml_suffix, 'String');
            stcObj.xmlSuffix = xmlSuffix;
            
            % Define options for minimum reccomendedoutput
            stcObj.referenceMethodIndex = referenceMethod;
            stcObj.SUMMARIZE_BANDS = 1;
            stcObj.EXPORT_BAND_SUMMARY = 1;
            stcObj.PLOT_CALIBRATION_TEST = 0;
            stcObj.PLOT_COMPREHENSIVE_SPECTRAL_SUMMARY = 1;
            stcObj.PLOT_HYPNOGRAM = 0;
            stcObj.PLOT_ARTIFACT_SUMMARY = 0;
            stcObj.PLOT_SPECTRAL_SUMMARY = 0;
            stcObj.PLOT_NREM_REM_SPECTRUM = 1;
            stcObj.OUTPUT_AVERAGE_SPECTROGRAMS = 1;
            stcObj.PLOT_BAND_ACTIVITY = 0;
            stcObj.artifactTH = [deltaTh betaTh];
            stcObj.figPos = monitorID;
            stcObj.GENERATE_FILE_LIST = 1;
            stcObj.CREATE_POWER_POINT_SUMMARY = 1;
            stcObj.EXPORT_SPECTRAL_DETAILS = 0;
            stcObj.COMPUTE_TOTAL_POWER = 0;
            stcObj.EXPORT_TOTAL_POWER = 0;
            
            % Coherence Parametes
            stcObj.COHERENCE_COMPUTE_COHERENCE = cb_fig_compute_coherence;
            
            
            % Spectral parameters
            if (pm_analysis_spectral_settings == 2)
                % Switch to SHHS settings
                stcObj.noverlap = 6;
                stcObj.spectralBinWidth = 5;
                stcObj.windowFunctionIndex = 3; % Hanning
                stcObj.AVERAGE_ADJACENT_BANDS = 0;
            end
            
            % Band settings
            if band_setting_fn_selected == 1
                % Load band structure
                bandFn = strcat(band_setting_pn, band_setting_fn);
                bandStruct = stcObj.LoadBandSettings(bandFn);
            
                % Set band variables
                stcObj.bandsOfInterest = bandStruct.bandsOfInterest;
                stcObj.bandsOfInterestLabels = bandStruct.bandsOfInterestLabels;
                stcObj.bandsOfInterestLatex = bandStruct.bandsOfInterestLatex;
                stcObj.bandColors = bandStruct.bandColors;
            end
            
            % Set start iterion
            stcObj.startFile = startFile;
            
            % Execute analysis
            stcObj = stcObj.computeSpectralTrain;
        end

        % Button pushed function: pb_fig_set_bands
        function pb_fig_set_bands_Callback(app, event)
            % --- Executes on button press in pb_fig_set_bands.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pb_fig_set_bands (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Start search in current path
            current_xls_path = handles.band_setting_pn;
            
            % Ask user to select a file
            [xls_fn xls_pn xls_file_is_selected ] = ...
                                                  pb_select_xls_file(current_xls_path);
            
            % Record file information if new file is selected
            if xls_file_is_selected == 1
                handles.band_setting_fn = xls_fn;
                handles.band_setting_pn = xls_pn;
                handles.band_setting_fn_selected = 1;
                handles.current_xls_path = xls_pn;
            end
            
            % Update handles structure
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Sleep Study';
            app.figure1.Tag = 'figure1';
            app.GridLayout = uigridlayout(app.figure1);
            app.uipanel2 = uipanel(app.GridLayout);
            app.uipanel2.Title = 'Analysis Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.text19 = uilabel(app.uipanel2);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Sampling Rate Partitioning Variable';
            app.e_analysis_parameters_analysis_signals = uieditfield(app.uipanel2, 'text');
            app.e_analysis_parameters_analysis_signals.Tag = 'e_analysis_parameters_analysis_signals';
            app.e_analysis_parameters_analysis_signals.Value = 'e_analysis_parameters_analysis_signals';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Analysis Signals';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Artifact Detection';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Slow (0.6-4.6Hz)';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Fast (20-40Hz)';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Reference Signals';
            app.e_analysis_parameters_reference_signals = uieditfield(app.uipanel2, 'text');
            app.e_analysis_parameters_reference_signals.Tag = 'e_analysis_parameters_reference_signals';
            app.e_analysis_parameters_reference_signals.Value = 'pb_reference_method';
            app.pm_analysis_parameters_delta = uidropdown(app.uipanel2);
            app.pm_analysis_parameters_delta.Items = {'1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', '1.8', '1.9', '2.0', '2.1', '2.2', '2.3', '2.4', '2.5', '2.6', '2.7', '2.8', '2.9', '3.0', '4.0', '5.0', '10.0', '20.0'};
            app.pm_analysis_parameters_delta.Tag = 'pm_analysis_parameters_delta';
            app.pm_analysis_parameters_delta.Value = '2.5';
            app.pm_analysis_parameters_beta = uidropdown(app.uipanel2);
            app.pm_analysis_parameters_beta.Items = {'1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', '1.8', '1.9', '2.0', '2.1', '2.2', '2.3', '2.4', '2.5', '2.6', '2.7', '2.8', '2.9', '3.0', '4.0', '5.0', '10.0', '20.0'};
            app.pm_analysis_parameters_beta.Tag = 'pm_analysis_parameters_beta';
            app.pm_analysis_parameters_beta.Value = '2.0';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Reference Method';
            app.pm_reference_method = uidropdown(app.uipanel2);
            app.pm_reference_method.Items = {'Single Reference Signal', 'Reference for each Analysis Signal', 'Average of Reference Signal'};
            app.pm_reference_method.Tag = 'pm_reference_method';
            app.pm_reference_method.Value = 'Single Reference Signal';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Spectral Settings';
            app.pm_analysis_spectral_settings = uidropdown(app.uipanel2);
            app.pm_analysis_spectral_settings.Items = {'10x4 sec sub-epochs, 50% Tukey', '6x5 sec sub-epochs, Hanning'};
            app.pm_analysis_spectral_settings.Tag = 'pm_analysis_spectral_settings';
            app.pm_analysis_spectral_settings.Value = '10x4 sec sub-epochs, 50% Tukey';
            app.e_analysis_sr_paritioning_varaible = uieditfield(app.uipanel2, 'text');
            app.e_analysis_sr_paritioning_varaible.Tag = 'e_analysis_sr_paritioning_varaible';
            app.e_analysis_sr_paritioning_varaible.Value = 'e_analysis_sr_paritioning_varaible';
            app.text21 = uilabel(app.uipanel2);
            app.text21.Tag = 'text21';
            app.text21.Text = 'ECG channel name';
            app.edit12 = uieditfield(app.uipanel2, 'text');
            app.edit12.Tag = 'edit12';
            app.edit12.Value = 'e_ecg';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Description';
            app.uipanel1.Tag = 'uipanel3';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Analysis Description';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Output File Prefix';
            app.pb_description_data_folder = uibutton(app.uipanel1, 'push');
            app.pb_description_data_folder.ButtonPushedFcn = createCallbackFcn(app, @pb_description_data_folder_Callback, true);
            app.pb_description_data_folder.Tag = 'pb_description_data_folder';
            app.pb_description_data_folder.Text = '...';
            app.pb_description_result_folder = uibutton(app.uipanel1, 'push');
            app.pb_description_result_folder.ButtonPushedFcn = createCallbackFcn(app, @pb_description_result_folder_Callback, true);
            app.pb_description_result_folder.Tag = 'pb_description_result_folder';
            app.pb_description_result_folder.Text = '...';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Data Folder';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Result Folder';
            app.e_description_analysis_description = uieditfield(app.uipanel1, 'text');
            app.e_description_analysis_description.Tag = 'e_description_analysis_description';
            app.e_description_analysis_description.Value = 'e_description_analysis_description';
            app.e_description_output_file_prefix = uieditfield(app.uipanel1, 'text');
            app.e_description_output_file_prefix.Tag = 'e_description_output_file_prefix';
            app.e_description_output_file_prefix.Value = 'e_description_output_file_prefix';
            app.e_description_data_folder = uieditfield(app.uipanel1, 'text');
            app.e_description_data_folder.Tag = 'e_description_data_folder';
            app.e_description_data_folder.Value = 'e_description_data_folder';
            app.e_description_result_folder = uieditfield(app.uipanel1, 'text');
            app.e_description_result_folder.Tag = 'e_description_result_folder';
            app.e_description_result_folder.Value = 'e_description_result_folder';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'XML Suffix';
            app.e_description_xml_suffix = uieditfield(app.uipanel1, 'text');
            app.e_description_xml_suffix.Tag = 'e_description_xml_suffix';
            app.e_description_xml_suffix.Value = 'e_description_xml_suffix';
            app.text22 = uilabel(app.uipanel1);
            app.text22.Tag = 'text20';
            app.text22.Text = 'Select Band Settings';
            app.pb_fig_set_bands = uibutton(app.uipanel1, 'push');
            app.pb_fig_set_bands.ButtonPushedFcn = createCallbackFcn(app, @pb_fig_set_bands_Callback, true);
            app.pb_fig_set_bands.Tag = 'pb_fig_set_bands';
            app.pb_fig_set_bands.Text = 'Set Bands of Interest';
            app.uipanel3 = uipanel(app.GridLayout);
            app.uipanel3.Title = 'Analysis';
            app.uipanel3.Tag = 'uipanel3';
            app.text23 = uilabel(app.uipanel3);
            app.text23.Tag = 'text1';
            app.text23.Text = 'Selection';
            app.checkbox3 = uicheckbox(app.uipanel3);
            app.checkbox3.Tag = 'checkbox3';
            app.checkbox3.Text = 'Cycle analysis';
            app.checkbox2 = uicheckbox(app.uipanel3);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'ECG decontamination';
            app.cb_fig_compute_coherence = uicheckbox(app.uipanel3);
            app.cb_fig_compute_coherence.Tag = 'cb_fig_compute_coherence';
            app.cb_fig_compute_coherence.Text = 'Coherence';
            app.text10 = uilabel(app.uipanel3);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Monitor ID';
            app.pm_analysis_parameters_monitor_id = uidropdown(app.uipanel3);
            app.pm_analysis_parameters_monitor_id.Items = {'pm_analysis_parameters_monitor_id'};
            app.pm_analysis_parameters_monitor_id.Tag = 'pm_analysis_parameters_monitor_id';
            app.pm_analysis_parameters_monitor_id.Value = 'pm_analysis_parameters_monitor_id';
            app.pm_analysis_start = uidropdown(app.uipanel3);
            app.pm_analysis_start.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61', '62', '63', '64', '65', '66', '67', '68', '69', '70', '71', '72', '73', '74', '75', '76', '77', '78', '79', '80', '81', '82', '83', '84', '85', '86', '87', '88', '89', '90', '91', '92', '93', '94', '95', '96', '97', '98', '99', '100', '101', '102', '103', '104', '105', '106', '107', '108', '109', '110', '111', '112', '113', '114', '115', '116', '117', '118', '119', '120', '121', '122', '123', '124', '125', '126', '127', '128', '129', '130', '131', '132', '133', '134', '135', '136', '137', '138', '139', '140', '141', '142', '143', '144', '145', '146', '147', '148', '149', '150', '151', '152', '153', '154', '155', '156', '157', '158', '159', '160', '161', '162', '163', '164', '165', '166', '167', '168', '169', '170', '171', '172', '173', '174', '175', '176', '177', '178', '179', '180', '181', '182', '183', '184', '185', '186', '187', '188', '189', '190', '191', '192', '193', '194', '195', '196', '197', '198', '199', '200', '201', '202', '203', '204', '205', '206', '207', '208', '209', '210', '211', '212', '213', '214', '215', '216', '217', '218', '219', '220', '221', '222', '223', '224', '225', '226', '227', '228', '229', '230', '231', '232', '233', '234', '235', '236', '237', '238', '239', '240', '241', '242', '243', '244', '245', '246', '247', '248', '249', '250', '251', '252', '253', '254', '255', '256', '257', '258', '259', '260', '261', '262', '263', '264', '265', '266', '267', '268', '269', '270', '271', '272', '273', '274', '275', '276', '277', '278', '279', '280', '281', '282', '283', '284', '285', '286', '287', '288', '289', '290', '291', '292', '293', '294', '295', '296', '297', '298', '299', '300', '301', '302', '303', '304', '305', '306', '307', '308', '309', '310', '311', '312', '313', '314', '315', '316', '317', '318', '319', '320', '321', '322', '323', '324', '325', '326', '327', '328', '329', '330', '331', '332', '333', '334', '335', '336', '337', '338', '339', '340', '341', '342', '343', '344', '345', '346', '347', '348', '349', '350', '351', '352', '353', '354', '355', '356', '357', '358', '359', '360', '361', '362', '363', '364', '365', '366', '367', '368', '369', '370', '371', '372', '373', '374', '375', '376', '377', '378', '379', '380', '381', '382', '383', '384', '385', '386', '387', '388', '389', '390', '391', '392', '393', '394', '395', '396', '397', '398', '399', '400', '401', '402', '403', '404', '405', '406', '407', '408', '409', '410', '411', '412', '413', '414', '415', '416', '417', '418', '419', '420', '421', '422', '423', '424', '425', '426', '427', '428', '429', '430', '431', '432', '433', '434', '435', '436', '437', '438', '439', '440', '441', '442', '443', '444', '445', '446', '447', '448', '449', '450', '451', '452', '453', '454', '455', '456', '457', '458', '459', '460', '461', '462', '463', '464', '465', '466', '467', '468', '469', '470', '471', '472', '473', '474', '475', '476', '477', '478', '479', '480', '481', '482', '483', '484', '485', '486', '487', '488', '489', '490', '491', '492', '493', '494', '495', '496', '497', '498', '499', '500', '501', '502', '503', '504', '505', '506', '507', '508', '509', '510', '511', '512', '513', '514', '515', '516', '517', '518', '519', '520', '521', '522', '523', '524', '525', '526', '527', '528', '529', '530', '531', '532', '533', '534', '535', '536', '537', '538', '539', '540', '541', '542', '543', '544', '545', '546', '547', '548', '549', '550', '551', '552', '553', '554', '555', '556', '557', '558', '559', '560', '561', '562', '563', '564', '565', '566', '567', '568', '569', '570', '571', '572', '573', '574', '575', '576', '577', '578', '579', '580', '581', '582', '583', '584', '585', '586', '587', '588', '589', '590', '591', '592', '593', '594', '595', '596', '597', '598', '599', '600', '601', '602', '603', '604', '605', '606', '607', '608', '609', '610', '611', '612', '613', '614', '615', '616', '617', '618', '619', '620', '621', '622', '623', '624', '625', '626', '627', '628', '629', '630', '631', '632', '633', '634', '635', '636', '637', '638', '639', '640', '641', '642', '643', '644', '645', '646', '647', '648', '649', '650', '651', '652', '653', '654', '655', '656', '657', '658', '659', '660', '661', '662', '663', '664', '665', '666', '667', '668', '669', '670', '671', '672', '673', '674', '675', '676', '677', '678', '679', '680', '681', '682', '683', '684', '685', '686', '687', '688', '689', '690', '691', '692', '693', '694', '695', '696', '697', '698', '699', '700', '701', '702', '703', '704', '705', '706', '707', '708', '709', '710', '711', '712', '713', '714', '715', '716', '717', '718', '719', '720', '721', '722', '723', '724', '725', '726', '727', '728', '729', '730', '731', '732', '733', '734', '735', '736', '737', '738', '739', '740', '741', '742', '743', '744', '745', '746', '747', '748', '749', '750', '751', '752', '753', '754', '755', '756', '757', '758', '759', '760', '761', '762', '763', '764', '765', '766', '767', '768', '769', '770', '771', '772', '773', '774', '775', '776', '777', '778', '779', '780', '781', '782', '783', '784', '785', '786', '787', '788', '789', '790', '791', '792', '793', '794', '795', '796', '797', '798', '799', '800', '801', '802', '803', '804', '805', '806', '807', '808', '809', '810', '811', '812', '813', '814', '815', '816', '817', '818', '819', '820', '821', '822', '823', '824', '825', '826', '827', '828', '829', '830', '831', '832', '833', '834', '835', '836', '837', '838', '839', '840', '841', '842', '843', '844', '845', '846', '847', '848', '849', '850', '851', '852', '853', '854', '855', '856', '857', '858', '859', '860', '861', '862', '863', '864', '865', '866', '867', '868', '869', '870', '871', '872', '873', '874', '875', '876', '877', '878', '879', '880', '881', '882', '883', '884', '885', '886', '887', '888', '889', '890', '891', '892', '893', '894', '895', '896', '897', '898', '899', '900', '901', '902', '903', '904', '905', '906', '907', '908', '909', '910', '911', '912', '913', '914', '915', '916', '917', '918', '919', '920', '921', '922', '923', '924', '925', '926', '927', '928', '929', '930', '931', '932', '933', '934', '935', '936', '937', '938', '939', '940', '941', '942', '943', '944', '945', '946', '947', '948', '949', '950', '951', '952', '953', '954', '955', '956', '957', '958', '959', '960', '961', '962', '963', '964', '965', '966', '967', '968', '969', '970', '971', '972', '973', '974', '975', '976', '977', '978', '979', '980', '981', '982', '983', '984', '985', '986', '987', '988', '989', '990', '991', '992', '993', '994', '995', '996', '997', '998', '999', '1000', '1100', '1200', '1300', '1400', '1500', '1600', '1700', '1800', '1900', '2000', '2100', '2200', '2300', '2400', '2500', '2600', '2700', '2800', '2900', '3000', '3100', '3200', '3300', '3400', '3500', '3600', '3700', '3800', '3900', '4000', '4100', '4200', '4300', '4400', '4500', '4600', '4700', '4800', '4900', '5000', '5100', '5200', '5300', '5400', '5500', '5600', '5700', '5800', '5900', '6000', '6100', '6200', '6300', '6400', '6500', '6600', '6700', '6800', '6900', '7000', '7100', '7200', '7300', '7400', '7500', '7600', '7700', '7800', '7900', '8000', '8100', '8200', '8300', '8400', '8500', '8600', '8700', '8800', '8900', '9000', '9100', '9200', '9300', '9400', '9500', '9600', '9700', '9800', '9900', '10000'};
            app.pm_analysis_start.Tag = 'pm_analysis_start';
            app.pm_analysis_start.Value = '1';
            app.text14 = uilabel(app.uipanel3);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Start:';
            app.pb_fig_go = uibutton(app.uipanel3, 'push');
            app.pb_fig_go.ButtonPushedFcn = createCallbackFcn(app, @pb_fig_go_Callback, true);
            app.pb_fig_go.Tag = 'pb_fig_go';
            app.pb_fig_go.Text = 'Go (all)';
            app.pb_fig_about = uibutton(app.uipanel3, 'push');
            app.pb_fig_about.ButtonPushedFcn = createCallbackFcn(app, @pb_fig_about_Callback, true);
            app.pb_fig_about.Tag = 'pb_fig_about';
            app.pb_fig_about.Text = 'About';
            app.pb_fig_go_min = uibutton(app.uipanel3, 'push');
            app.pb_fig_go_min.ButtonPushedFcn = createCallbackFcn(app, @pb_fig_go_min_Callback, true);
            app.pb_fig_go_min.Tag = 'pb_fig_go_min';
            app.pb_fig_go_min.Text = 'Go (min)';
            app.pb_close_all = uibutton(app.uipanel3, 'push');
            app.pb_close_all.ButtonPushedFcn = createCallbackFcn(app, @pb_close_all_Callback, true);
            app.pb_close_all.Tag = 'pb_close_all';
            app.pb_close_all.Text = 'Close All';
            app.pb_fig_all_lists = uibutton(app.uipanel3, 'push');
            app.pb_fig_all_lists.ButtonPushedFcn = createCallbackFcn(app, @pb_fig_all_lists_Callback, true);
            app.pb_fig_all_lists.Tag = 'pb_fig_all_lists';
            app.pb_fig_all_lists.Text = 'All Lists';
            app.pb_fig_band = uibutton(app.uipanel3, 'push');
            app.pb_fig_band.ButtonPushedFcn = createCallbackFcn(app, @pb_fig_band_Callback, true);
            app.pb_fig_band.Tag = 'pb_fig_band';
            app.pb_fig_band.Text = 'Band';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralTrainFig(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SpectralTrainFig_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end